classdef Main_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PatientIDEditFieldLabel       matlab.ui.control.Label
        PatientIDEditField            matlab.ui.control.EditField
        AcquireButton                 matlab.ui.control.Button
        WMRSRamanButton               matlab.ui.control.Button
        EngineeringModeButton         matlab.ui.control.Button
        AbortButton                   matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        SpectraAquiredEditFieldLabel  matlab.ui.control.Label
        SpectraAquiredEditField       matlab.ui.control.NumericEditField
        SetSavePathButton             matlab.ui.control.Button
        LaserPowerButton              matlab.ui.control.Button
        CentralWavelengthButton       matlab.ui.control.Button
        SlitWidthButton               matlab.ui.control.Button
        TuningStepsButton             matlab.ui.control.Button
        MinWavelengthButton           matlab.ui.control.Button
        CCDTempButton                 matlab.ui.control.Button
        MinRamanShiftButton           matlab.ui.control.Button
        MaxWavelengthButton           matlab.ui.control.Button
        MaxRamanShiftButton           matlab.ui.control.Button
        LaserShutterButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpectraAcquired = 0 % Number of spectra acquired
        PatientId % Patient Id
        SaveDir % Path to directory to save spectra
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            app.SpectraAcquired = app.SpectraAcquired + 1;
            app.SpectraAquiredEditField.Value = app.SpectraAcquired;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SetSavePathButton
        function SetSavePathButtonPushed(app, event)
            app.SaveDir = uigetdir("", "Patient data Folder");
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientId = value;
        end

        % Button pushed function: EngineeringModeButton
        function EngineeringModeButtonPushed(app, event)
            % run("Password.mlapp");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.WMRSRamanButton = uibutton(app.UIFigure, 'push');
            app.WMRSRamanButton.Text = 'WMRS/Raman';
            app.EngineeringModeButton = uibutton(app.UIFigure, 'push');
            app.EngineeringModeButton.ButtonPushedFcn = createCallbackFcn(app, @EngineeringModeButtonPushed, true);
            app.EngineeringModeButton.Text = 'Engineering Mode >>>';
            app.AbortButton = uibutton(app.UIFigure, 'push');
            app.AbortButton.Text = 'Abort';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SpectraAquiredEditFieldLabel = uilabel(app.UIFigure);
            app.SpectraAquiredEditFieldLabel.Text = 'Spectra Aquired:';
            app.SpectraAquiredEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectraAquiredEditField.Limits = [0 Inf];
            app.SpectraAquiredEditField.ValueDisplayFormat = '%.0f';
            app.SetSavePathButton = uibutton(app.UIFigure, 'push');
            app.SetSavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetSavePathButtonPushed, true);
            app.SetSavePathButton.Text = 'Set Save Path';
            app.LaserPowerButton = uibutton(app.UIFigure, 'push');
            app.LaserPowerButton.Text = 'Laser Power';
            app.CentralWavelengthButton = uibutton(app.UIFigure, 'push');
            app.CentralWavelengthButton.Text = 'Central Wavelength';
            app.SlitWidthButton = uibutton(app.UIFigure, 'push');
            app.SlitWidthButton.Text = 'Slit Width';
            app.TuningStepsButton = uibutton(app.UIFigure, 'push');
            app.TuningStepsButton.Text = 'Tuning Steps';
            app.MinWavelengthButton = uibutton(app.UIFigure, 'push');
            app.MinWavelengthButton.Text = 'Min Wavelength';
            app.CCDTempButton = uibutton(app.UIFigure, 'push');
            app.CCDTempButton.Text = 'CCD Temp';
            app.MinRamanShiftButton = uibutton(app.UIFigure, 'push');
            app.MinRamanShiftButton.Text = 'Min Raman Shift';
            app.MaxWavelengthButton = uibutton(app.UIFigure, 'push');
            app.MaxWavelengthButton.Text = 'Max Wavelength';
            app.MaxRamanShiftButton = uibutton(app.UIFigure, 'push');
            app.MaxRamanShiftButton.Text = 'Max Raman Shift';
            app.LaserShutterButton = uibutton(app.UIFigure, 'push');
            app.LaserShutterButton.Text = 'Laser Shutter';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raman Spectra')
            xlabel(app.UIAxes, 'Wavenumber/c,m^-1')
            ylabel(app.UIAxes, 'Counts/arb.')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end