classdef calibration_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CalibrateButton         matlab.ui.control.Button
        SavePathEditFieldLabel  matlab.ui.control.Label
        SavePathEditField       matlab.ui.control.EditField
        SaveButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            free_bytes = java.io.File(".").getFreeSpace();
            free_Gbytes = free_bytes / (1024^3);
            if free_Gbytes < 5
                uiwait(msgbox("Not Enough Memory on Hard Drive!","Memory Error","error", "modal"));
                delete(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save code
            %blah
            
            delete(app);
            run("Main_GUI.mlapp");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.SavePathEditFieldLabel = uilabel(app.UIFigure);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration Spectra')
            xlabel(app.UIAxes, 'Wavenumber/cm^-1')
            ylabel(app.UIAxes, 'Intensity/arb.')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibration_popup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end